classdef LabelRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelRecognitionUIFigure    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayoutLeft              matlab.ui.container.GridLayout
        FileLocationField           matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        RecogniseDatesButton        matlab.ui.control.Button
        ConfigurationLabel          matlab.ui.control.Label
        MSERDeltaSliderLabel        matlab.ui.control.Label
        MSERDeltaSlider             matlab.ui.control.Slider
        SWTThresholdSliderLabel     matlab.ui.control.Label
        SWTThresholdSlider          matlab.ui.control.Slider
        OrientationThresholdSliderLabel  matlab.ui.control.Label
        OrientationThresholdSlider  matlab.ui.control.Slider
        RightPanel                  matlab.ui.container.Panel
        GridLayoutRight             matlab.ui.container.GridLayout
        Image                       matlab.ui.control.Image
        RuntimeLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Properties relating to app data
    properties (Access = private)
        imageMatrix % N-by-M matrix of the user selected image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %TODO:
            %Handle image read + assignment elegantly - maybe in same try block?
            %Assign unchanged image to imageMatrix
            %use uialert to show errors
            %Component resizing...
            
            % Select image using GUI
            types = {'*.jpeg;*.jpg;*.png;*.gif;*.tiff', ...
                'Image Files (*.jpeg, *.jpg, *.png, *.gif, *.tiff)'}; 
            [file, path] = uigetfile(types, 'Browse Images');
            
            % Check that a file was selected
            if (~isequal(file, 0))
                filepath = fullfile(path, file);
                
                try 
                    I = imread(filepath);
                catch ME
                    uialert(app.LabelRecognitionUIFigure, ME.message, 'Invalid File Type');
                end
                
                if size(I, 3) < 3
                    app.Image.ImageSource = cat(3, I, I, I);
                else
                    app.Image.ImageSource = I;
                end                
                % Assign selected file to field and image box
                app.FileLocationField.Value = filepath;
            end
            
            % Return to UIFigure
            drawnow;
            figure(app.LabelRecognitionUIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LabelRecognitionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelRecognitionUIFigure = uifigure('Visible', 'off');
            app.LabelRecognitionUIFigure.Name = 'Label Recognition';
            app.LabelRecognitionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LabelRecognitionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.FileLocationField = uieditfield(app.GridLayoutLeft, 'text');
            app.FileLocationField.Editable = 'off';
            app.FileLocationField.Tooltip = {'Image File Path'};
            app.BrowseButton = uibutton(app.GridLayoutLeft, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.RecogniseDatesButton = uibutton(app.GridLayoutLeft, 'push');
            app.RecogniseDatesButton.Text = 'Recognise Dates';
            app.ConfigurationLabel = uilabel(app.GridLayoutLeft);
            app.ConfigurationLabel.Text = 'Configuration';
            app.MSERDeltaSliderLabel = uilabel(app.GridLayoutLeft);
            app.MSERDeltaSliderLabel.Text = 'MSER Delta';
            app.MSERDeltaSlider = uislider(app.GridLayoutLeft);
            app.MSERDeltaSlider.Limits = [0 1];
            app.SWTThresholdSliderLabel = uilabel(app.GridLayoutLeft);
            app.SWTThresholdSliderLabel.Text = 'SWT Threshold';
            app.SWTThresholdSlider = uislider(app.GridLayoutLeft);
            app.SWTThresholdSlider.Limits = [0 1];
            app.SWTThresholdSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.SWTThresholdSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.OrientationThresholdSliderLabel = uilabel(app.GridLayoutLeft);
            app.OrientationThresholdSliderLabel.Text = 'Orientation Threshold';
            app.OrientationThresholdSlider = uislider(app.GridLayoutLeft);
            app.OrientationThresholdSlider.Limits = [0 1];
            app.OrientationThresholdSlider.MajorTicks = [0 0.33 0.66 1];
            app.OrientationThresholdSlider.MajorTickLabels = {'0', '0.33', '0.66', '1'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRight = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayoutRight);
            app.RuntimeLabel = uilabel(app.GridLayoutRight);
            app.RuntimeLabel.Text = 'Completed in: {0} secs';
            % Show the figure after all components are created
            app.LabelRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelRecognitionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelRecognitionUIFigure)
        end
    end
end